classdef ClientGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AudioServerClientGUIUIFigure  matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        PlaybackTab                   matlab.ui.container.Tab
        PlaybackLamp                  matlab.ui.control.Lamp
        PlaybackTypeSwitch            matlab.ui.control.Switch
        FromFilesPanel                matlab.ui.container.Panel
        GridLayoutFromFiles           matlab.ui.container.GridLayout
        FromFilesPanelInfo            matlab.ui.control.Label
        PlaybackRefreshListsButton    matlab.ui.control.Button
        FilterPanel                   matlab.ui.container.Panel
        GridLayoutPlaybackFilter      matlab.ui.container.GridLayout
        FilterAudioFilesButton        matlab.ui.control.StateButton
        FilterPlaylistsButton         matlab.ui.control.StateButton
        FromFileDropDown              matlab.ui.control.DropDown
        PlayButtonFromFiles           matlab.ui.control.StateButton
        PlaybackFromFilesLogs         matlab.ui.control.TextArea
        GeneratePanel                 matlab.ui.container.Panel
        GridLayoutGenerate            matlab.ui.container.GridLayout
        SendtoCreateButton            matlab.ui.control.Button
        GeneratePanelInfo             matlab.ui.control.Label
        GenerateTypePanel             matlab.ui.container.Panel
        GridLayoutGenerateType        matlab.ui.container.GridLayout
        GenerateToneTypeButton        matlab.ui.control.StateButton
        GenerateSweepTypeButton       matlab.ui.control.StateButton
        FrequencyHzLabel              matlab.ui.control.Label
        FrequencyHzSpinner            matlab.ui.control.Spinner
        EndFrequencyHzLabel           matlab.ui.control.Label
        EndFrequencyHzSpinner         matlab.ui.control.Spinner
        DurationmsSpinner             matlab.ui.control.Spinner
        AmplitudedBSpinner            matlab.ui.control.Spinner
        SampleRateHzSpinner           matlab.ui.control.Spinner
        EdgesmsSpinner                matlab.ui.control.Spinner
        EdgesmsSpinnerLabel           matlab.ui.control.Label
        SampleRateHzSpinnerLabel      matlab.ui.control.Label
        AmplitudedBSpinnerLabel       matlab.ui.control.Label
        DurationmsSpinnerLabel        matlab.ui.control.Label
        EdgesmsHelp                   matlab.ui.control.Label
        SweepFunctionLabel            matlab.ui.control.Label
        SweepFnDropDown               matlab.ui.control.DropDown
        PlayButtonGenerateAudio       matlab.ui.control.StateButton
        PlaybackGenerateAudioLog      matlab.ui.control.TextArea
        CreateTab                     matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        CreateLamp                    matlab.ui.control.Lamp
        CreateRandomPlaylist          matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        ToneSweepPanel                matlab.ui.container.Panel
        GridLayoutCreateToneSweep     matlab.ui.container.GridLayout
        CreateToneSweepInfo           matlab.ui.control.Label
        GenerateTypePanel_2           matlab.ui.container.Panel
        GridLayoutGenerateType_2      matlab.ui.container.GridLayout
        GenerateToneTypeButton_2      matlab.ui.control.StateButton
        GenerateSweepTypeButton_2     matlab.ui.control.StateButton
        FrequencyHzLabel_2            matlab.ui.control.Label
        FrequencyHzSpinner_2          matlab.ui.control.Spinner
        EndFrequencyHzLabel_2         matlab.ui.control.Label
        EndFrequencyHzSpinner_2       matlab.ui.control.Spinner
        EdgesmsSpinner_2              matlab.ui.control.Spinner
        EdgesmsSpinner_2Label         matlab.ui.control.Label
        SampleRateHzSpinner_2         matlab.ui.control.Spinner
        SampleRateHzSpinner_2Label    matlab.ui.control.Label
        AmplitudedBSpinner_2          matlab.ui.control.Spinner
        AmplitudedBSpinner_2Label     matlab.ui.control.Label
        DurationmsSpinner_2           matlab.ui.control.Spinner
        DurationmsSpinner_2Label      matlab.ui.control.Label
        EdgesmsHelp_2                 matlab.ui.control.Label
        SweepFunctionLabel_2          matlab.ui.control.Label
        SweepFnDropDown_2             matlab.ui.control.DropDown
        PlayButtonGenerateAudio_2     matlab.ui.control.StateButton
        WindowsBatchFilesPanel        matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        DownloadaszipButton           matlab.ui.control.StateButton
        BatchFilesInfo                matlab.ui.control.Label
        BatchFilesDropDown            matlab.ui.control.DropDown
        InformationTab                matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        AvailableAudioFilesPlaylistsButton  matlab.ui.control.Button
        MainAPIDocumentationButton    matlab.ui.control.Button
        InformationPanel              matlab.ui.control.TextArea
        SettingsTab                   matlab.ui.container.Tab
        GridLayoutSettings            matlab.ui.container.GridLayout
        ServerIPPortEditField         matlab.ui.control.EditField
        RestartServerLabel            matlab.ui.control.Label
        RestartButton                 matlab.ui.control.Button
        OrusetheURIbelowtoRestarttheservermanuallyLabel  matlab.ui.control.Label
        ManualRestartURI              matlab.ui.control.TextArea
        ShutdownServerLabel           matlab.ui.control.Label
        ShutdownButton                matlab.ui.control.Button
        ServerIPPortLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        AudioFilesList % Collection of Available Audio Files
        PlaylistsList % Collection of Available (Validated) Playlists
    end
    
    methods (Access = private)

        function setStatusLampColor_tip(~, lampTarget, colorHex, tooltip)
        %SETSTATUSLAMPCOLOR Set color of the indicator lamp and display a message
        
            % preset colors, otherwise use the stated hex string in colorHex
            if colorHex == "orange"
                colorHex = "#edcdb2";
                if isempty(tooltip) || ~exist('tooltip','var')
                    tooltip = "Working...";
                end
            elseif colorHex == "green" || ~exist('tooltip','var')
                colorHex = "#bdd9b0";
                if isempty(tooltip)
                    tooltip = "Ready";
                end
            elseif colorHex == "red" || ~exist('tooltip','var')
                colorHex = "#b82121";
                if isempty(tooltip)
                    tooltip = "Error!";
                end
            end
        
            if ~isempty(tooltip)
                lampTarget.Tooltip = sprintf(tooltip);
            else
                lampTarget.Tooltip = "Status";
            end
        
            lampTarget.Color = colorHex;
        end

                
        function handle_HTTP_error(app, ME, callButtonOrigin, lampTarget, logBoxTarget)
            function reset_interface(callButtonOrigin, lampTarget)
                if isequal(class(callButtonOrigin),'matlab.ui.control.StateButton')
                    callButtonOrigin.Value = false;
                end
                
                callButtonOrigin.Enable = "on";
                if isempty(lampTarget)
                    return
                end
                setStatusLampColor_tip(app, lampTarget, "green", "Ready!");
            end

            identifier = ME.identifier;
            message = ME.message;

            % if identifier is 'MATLAB:webservices:ConnectionRefused'
            % AND message ends with 'Recv failure: Connection was reset'
            % then the server was restarted successfully
            % Most likely the intended behavior
            if identifier == "MATLAB:webservices:ConnectionRefused" && endsWith(message, "Recv failure: Connection was reset")
                reset_interface(callButtonOrigin, lampTarget);
                if ~isempty(logBoxTarget)
                    logBoxTarget.Value = sprintf("Connection was reset. If audio was playing, it should have been stopped.\nIf you were trying to restart the audio server to stop the playing audio but it did not work, you may need to stop the server manually.");
                end
            % if identifier is 'MATLAB:webservices:ConnectionRefused'
            % AND message ends with 'Couldn't connect to server'
            % then the server is not online or not reachable
            elseif identifier == "MATLAB:webservices:ConnectionRefused" && endsWith(message, "Couldn't connect to server")
                reset_interface(callButtonOrigin, lampTarget);
                if ~isempty(logBoxTarget)
                    logBoxTarget.Value = sprintf("Cannot connect to the audio server. Is the audio server running and reachable?");
                end
                if ~isempty(lampTarget) 
                    setStatusLampColor_tip(app, lampTarget, "red", "Cannot connect to the audio server. Is the audio server running and reachable?\n\nTry to restart the server manually, or try to open the 'ServerIP:Port' address in a browser to check if the server is online.");
                
                end
            end
        end


        function response = app_HTTP_request(app, request, uri, callButtonOrigin, lampTarget, logBoxTarget)
            try
                response = send(request, uri);
                drawnow
            catch ME
                disp(ME)
                fprintf('Cannot send GET request, or the connection was interupted.\n\n')
                response.error = ME;

                handle_HTTP_error(app, ME, callButtonOrigin, lampTarget, logBoxTarget);
            end
        end


        function comfirm_decision = shutdown_confirm_callback(~, ~, event)
            if event.SelectedOption == "Shut it down!"
                comfirm_decision = true;
            else
                comfirm_decision = false;
            end
        end

    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            format longG

            app.ManualRestartURI.Value = strcat(strip(app.ServerIPPortEditField.Value,'right','/'), "/restart", '?restart=true');

            
            % Update the Information Panel with the API documentation
            request = matlab.net.http.RequestMessage;

            uri = strcat(strip(app.ServerIPPortEditField.Value,'right','/'), '/');
            response = app_HTTP_request(app, request, uri, app.MainAPIDocumentationButton, app.PlaybackLamp, app.InformationPanel);
            if isempty(response) || isfield(response, "error")
                return
            end
            app.InformationPanel.Value = response.Body.Data;
        end

        % Value changed function: ServerIPPortEditField
        function ServerIPPortEditFieldValueChanged(app, event)
            value = app.ServerIPPortEditField.Value;
            if ~startsWith(value, ["http://", "https://"])
                value = strcat('http://', value);
                app.ServerIPPortEditField.Value = value;
            end

            uri = strcat(strip(app.ServerIPPortEditField.Value,'right','/'), "/restart", '?restart=true');

            app.ManualRestartURI.Value = uri;

        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            app.RestartButton.Enable = "off";
            drawnow
            request = matlab.net.http.RequestMessage;
            uri = strcat(strip(app.ServerIPPortEditField.Value,'right','/'), "/restart", '?restart=true');

            response = app_HTTP_request(app, request, uri, app.RestartButton, [], []);
            drawnow
            if isempty(response) || isfield(response, "error")
                errordlg(sprintf("Something went wrong while trying to send the restart request.\n\nIs the server online and reachable? If so, please check the output on the server console.\nYou may need to restart the server manually."),...
                    "Restart Request Error!")
                return
            end
            
            msgbox("The restart request is sent successfully. Typically, the server should be rebooted within 7 seconds. To check if the server is back, go to Playback > From Files and hit the Refresh button.",...
                "Status")
            app.RestartButton.Enable = "on";
        end

        % Button pushed function: ShutdownButton
        function ShutdownButtonPushed(app, event)
            app.ShutdownButton.Enable = "off";
            drawnow
            

            comfirm_decision = uiconfirm(app.AudioServerClientGUIUIFigure,sprintf('Shutdown the audio server. Literally, shut it down. Gone.\n\nIf you do not have access to the server computer, you will NOT be able to start the audio server app back up.\n\nUnless you want to actually shutdown (close) the audio server app, consider using the Restart option instead.'),'Are you sure you want to shutdown the audio server app?', ...
                "Options",["Shut it down!", "Cancel"], ...
                "DefaultOption",2,"CancelOption",2, ...
                'CloseFcn', @app.shutdown_confirm_callback);
        
            if comfirm_decision == "Cancel"
                app.ShutdownButton.Enable = "on";
                return
            end

            request = matlab.net.http.RequestMessage;
            uri = strcat(strip(app.ServerIPPortEditField.Value,'right','/'), "/shutdown", '?shutdown=iamsureshutmedown_YES');

            response = app_HTTP_request(app, request, uri, app.ShutdownButton, [], []);
            drawnow
            if isempty(response) || isfield(response, "error")
                errordlg(sprintf("Something went wrong while trying to send the shutdown request.\n\nIs the server online and reachable? If so, please check the output on the server console.\nYou may need to shutdown the server manually."),...
                    "Shutdown Request Error!")
                return
            end

            msgbox("The shutdown request is sent successfully. The audio server app should have shutdown gracefully.",...
                "Shutdown")
            app.ShutdownButton.Enable = "on";
        end

        % Value changed function: PlaybackTypeSwitch
        function PlaybackTypeSwitchValueChanged(app, event)
            value = app.PlaybackTypeSwitch.Value;
            if value == "From Files"
                app.FromFilesPanel.Enable = "on";
                app.GeneratePanel.Enable = "off";
            elseif value == "Generate"
                app.GeneratePanel.Enable = "on";
                app.FromFilesPanel.Enable = "off";
            end
        end

        % Value changed function: FilterAudioFilesButton
        function FilterAudioFilesButtonValueChanged(app, event)
            if app.FilterPlaylistsButton.Value == 0
                app.FilterAudioFilesButton.Value = 1;
                return
            end

            app.FilterPlaylistsButton.Value = 0;
            app.FilterAudioFilesButton.Value = 1;

            if isempty(app.AudioFilesList)
                app.PlaybackFromFilesLogs.Value = sprintf("No Audio Files available.");
                return
            end

            % Filter for only Audio Files and display on dropdown list
            % Update the current active Filter Dropdown
            app.FromFileDropDown.Items = app.AudioFilesList;
            app.FromFileDropDown.Value = app.AudioFilesList(1);
        end

        % Value changed function: FilterPlaylistsButton
        function FilterPlaylistsButtonValueChanged(app, event)
            if app.FilterAudioFilesButton.Value == 0
                app.FilterPlaylistsButton.Value = 1;
                return
            end

            app.FilterAudioFilesButton.Value = 0;
            app.FilterPlaylistsButton.Value = 1;

            if isempty(app.PlaylistsList)
                app.PlaybackFromFilesLogs.Value = sprintf("No Playlists available.");
                return
            end

            % Filter for only Playlists and display on dropdown list
            % Update the current active Filter Dropdown
            app.FromFileDropDown.Items = app.PlaylistsList;
            app.FromFileDropDown.Value = app.PlaylistsList(1);
        end

        % Value changed function: GenerateToneTypeButton
        function GenerateToneTypeButtonValueChanged(app, event)
            if app.GenerateSweepTypeButton.Value == 0
                app.GenerateToneTypeButton.Value = 1;
                return
            end
            app.GenerateSweepTypeButton.Value = 0;
            app.GenerateToneTypeButton.Value = 1;

            % Disable End Freq, Sweep Type options,
            % Also rename Start Frequency to just Frequency
            app.FrequencyHzLabel.Text = "Frequency (Hz)";
            app.EndFrequencyHzLabel.Enable = "off";
            app.EndFrequencyHzSpinner.Enable = "off";
            app.SweepFunctionLabel.Enable = "off";
            app.SweepFnDropDown.Enable = "off";
        end

        % Value changed function: GenerateSweepTypeButton
        function GenerateSweepTypeButtonValueChanged(app, event)
            if app.GenerateToneTypeButton.Value == 0
                app.GenerateSweepTypeButton.Value = 1;
                return
            end
            app.GenerateToneTypeButton.Value = 0;
            app.GenerateSweepTypeButton.Value = 1;

            % Enable End Freq, Sweep Type options,
            % Also rename Frequency to Start Frequency
            app.FrequencyHzLabel.Text = "Start Frequency (Hz)";
            app.EndFrequencyHzLabel.Enable = "on";
            app.EndFrequencyHzSpinner.Enable = "on";
            app.SweepFunctionLabel.Enable = "on";
            app.SweepFnDropDown.Enable = "on";
        end

        % Button pushed function: PlaybackRefreshListsButton
        function PlaybackRefreshListsButtonPushed(app, event)
            app.PlaybackRefreshListsButton.Enable = "off";
            setStatusLampColor_tip(app, app.PlaybackLamp, "orange", "Loading available audio files and playlists...\nPlease wait for the this to complete before doing anything else!");

            request = matlab.net.http.RequestMessage;
            uri = strcat(strip(app.ServerIPPortEditField.Value,'right','/'), '/list', '?json=true');
            app.PlaybackFromFilesLogs.Value = append('GET ', uri);
            drawnow
            
            getList = app_HTTP_request(app, request, uri, app.PlaybackRefreshListsButton, app.PlaybackLamp, app.PlaybackFromFilesLogs);
            if isempty(getList) || isfield(getList, "error")
                return
            end

            list = getList.Body.Data;
            % Update AudioFilesList and PlaylistsList
            app.AudioFilesList = list.audio;
            app.PlaylistsList = list.playlist;

            app.PlaybackFromFilesLogs.Value = append('Status: ', string(getList.StatusLine), newline, jsonencode(list, "PrettyPrint", true));

            % Update the current active Filter Dropdown
            if ~isempty(app.AudioFilesList) || ~isempty(app.PlaylistsList)
                if app.FilterAudioFilesButton.Value == 1
                    if ~isempty(app.AudioFilesList)
                        app.FromFileDropDown.Items = app.AudioFilesList;
                        app.FromFileDropDown.Value = app.AudioFilesList(1);
                    else
                        app.FromFileDropDown.Items = "";
                        app.FromFileDropDown.Value = '';
                        app.FilterAudioFilesButton.Value = 0;
                        app.FilterPlaylistsButton.Value = 1;
                    end
                    
                elseif app.FilterPlaylistsButton.Value == 1
                    if ~isempty(app.PlaylistsList)
                        app.FromFileDropDown.Items = app.PlaylistsList;
                        app.FromFileDropDown.Value = app.PlaylistsList(1);
                    else
                        app.FromFileDropDown.Items = "";
                        app.FromFileDropDown.Value = '';
                        app.FilterPlaylistsButton.Value = 0;
                        app.FilterAudioFilesButton.Value = 1;
                    end
                end
            else
                app.FromFileDropDown.Items = "";
                app.FromFileDropDown.Value = '';
                app.PlaybackFromFilesLogs.Value = sprintf("No Audio Files or Playlists available.\n\nYou may still use the Generate Panel below instead.");
            end

            
            app.PlaybackRefreshListsButton.Enable = "on";
            setStatusLampColor_tip(app, app.PlaybackLamp, "green", "Ready!");
        end

        % Value changed function: PlayButtonFromFiles
        function PlayButtonFromFilesValueChanged(app, event)
            app.PlayButtonFromFiles.Enable = "off";
            setStatusLampColor_tip(app, app.PlaybackLamp, "orange", "Playing Audio...\n\nThe audio cannot be stopped until it is finished, unless the server is shutdown.\nPlease wait for the playback to complete before doing anything else!");

            if app.FilterAudioFilesButton.Value == 1
                requestType = 'play';
            elseif app.FilterPlaylistsButton.Value == 1
                requestType = 'playlist';
            end
            
            audio = app.FromFileDropDown.Value;

            if isempty(audio)
                app.PlaybackFromFilesLogs.Value = sprintf("An audio file or playlist must be selected.\nMake sure the audio server is started and reachable, then hit the 'Refresh Lists' button.");
                app.PlayButtonFromFiles.Value = false;
                app.PlayButtonFromFiles.Enable = "on";
                setStatusLampColor_tip(app, app.PlaybackLamp, "red", "An audio file or playlist must be selected.");
                return
            end

            request = matlab.net.http.RequestMessage;
            uri = strcat(strip(app.ServerIPPortEditField.Value,'right','/'), '/', requestType, '/', audio, '?time=');

            timeMicro = string(posixtime(datetime('now', TimeZone = 'local', Format = 'yyyy-MM-dd HH:mm:ss.SSSSSS'))*1000000);
            uri = strcat(uri, timeMicro, '000');
            app.PlaybackFromFilesLogs.Value = append('GET ', uri);
            drawnow
            
            response = app_HTTP_request(app, request, uri, app.PlayButtonFromFiles, app.PlaybackLamp, app.PlaybackFromFilesLogs);
            if isempty(response) || isfield(response, "error")
                return
            end

            app.PlaybackFromFilesLogs.Value = append('Status: ', string(response.StatusLine), newline, jsonencode(response.Body.Data, "PrettyPrint", true));

            app.PlayButtonFromFiles.Value = false;
            app.PlayButtonFromFiles.Enable = "on";
            setStatusLampColor_tip(app, app.PlaybackLamp, "green", "Ready!");
        end

        % Value changed function: PlayButtonGenerateAudio
        function PlayButtonGenerateAudioValueChanged(app, event)
            app.PlayButtonGenerateAudio.Enable = "off";
            setStatusLampColor_tip(app, app.PlaybackLamp, "orange", "Playing Audio...\n\nThe audio cannot be stopped until it is finished, unless the server is shutdown.\nPlease wait for the playback to complete before doing anything else!");

            if app.GenerateToneTypeButton.Value == 1
                requestType = 'tone';
                data = strcat(num2str(app.FrequencyHzSpinner.Value), '/', num2str(app.DurationmsSpinner.Value), '/', ...
                    num2str(app.AmplitudedBSpinner.Value), '/', num2str(app.SampleRateHzSpinner.Value));
            elseif app.GenerateSweepTypeButton.Value == 1
                requestType = 'sweep';
                data = strcat(lower(app.SweepFnDropDown.Value), '/', num2str(app.FrequencyHzSpinner.Value), '/', ...
                    num2str(app.EndFrequencyHzSpinner.Value), '/', num2str(app.DurationmsSpinner.Value), '/', ...
                    num2str(app.AmplitudedBSpinner.Value), '/', num2str(app.SampleRateHzSpinner.Value));
            end

            request = matlab.net.http.RequestMessage;
            uri = strcat(strip(app.ServerIPPortEditField.Value,'right','/'), '/', requestType, '/', data, '?time=');

            timeMicro = string(posixtime(datetime('now', TimeZone = 'local', Format = 'yyyy-MM-dd HH:mm:ss.SSSSSS'))*1000000);
            uri = strcat(uri, timeMicro, '000', '&edge=', num2str(app.EdgesmsSpinner.Value));
            app.PlaybackGenerateAudioLog.Value = append('GET ', uri);
            drawnow
            
            response = app_HTTP_request(app, request, uri, app.PlayButtonGenerateAudio, app.PlaybackLamp, app.PlaybackGenerateAudioLog);
            if isempty(response) || isfield(response, "error")
                return
            end

            app.PlaybackGenerateAudioLog.Value = append('Status: ', string(response.StatusLine), newline, jsonencode(response.Body.Data, "PrettyPrint", true));

            app.PlayButtonGenerateAudio.Value = false;
            app.PlayButtonGenerateAudio.Enable = "on";
            setStatusLampColor_tip(app, app.PlaybackLamp, "green", "Ready!");
        end

        % Value changed function: DownloadaszipButton
        function DownloadaszipButtonValueChanged(app, event)
            app.DownloadaszipButton.Enable = "off";
            setStatusLampColor_tip(app, app.CreateLamp, "orange", "Saving batch files...\n\nPlease select the save location when prompted.");
            drawnow

            % determine the route from dropdown list
            dropdownValue = app.BatchFilesDropDown.Value;
            if dropdownValue == "Asynchronous Batch Files"
                route = "generate_batch_files_async";
            elseif dropdownValue == "Synchronous Batch Files"
                route = "generate_batch_files";
            end

            request = matlab.net.http.RequestMessage;
            uri = strcat(strip(app.ServerIPPortEditField.Value,'right','/'), '/', route);
            response = app_HTTP_request(app, request, uri, app.DownloadaszipButton, app.CreateLamp, []);
            if isempty(response) || isfield(response, "error")
                return
            end

            zipfileName = char(response.Header(2).Value); % Get the default filename from the Header
            zipfileName = string(zipfileName(22:end)); % trim the tag in front: "attachment; filename=_____.zip"

            [file,path] = uiputfile('*.zip','Save Location',zipfileName);

            if ischar(file) && ischar(path)
                zipPath = fullfile(path, file);
                fileID = fopen(zipPath,'w');
                fwrite(fileID, response.Body.Data);
                fclose(fileID);
            end

            app.DownloadaszipButton.Value = false;
            app.DownloadaszipButton.Enable = "on";
            setStatusLampColor_tip(app, app.CreateLamp, "green", "Ready!");
        end

        % Button pushed function: MainAPIDocumentationButton
        function MainAPIDocumentationButtonPushed(app, event)
            app.MainAPIDocumentationButton.Enable = "off";
            drawnow

            request = matlab.net.http.RequestMessage;
            uri = strcat(strip(app.ServerIPPortEditField.Value,'right','/'), '/');
            
            response = app_HTTP_request(app, request, uri, app.MainAPIDocumentationButton, [], app.InformationPanel);
            if isempty(response) || isfield(response, "error")
                return
            end

            app.InformationPanel.Value = response.Body.Data;

            app.MainAPIDocumentationButton.Enable = "on";
        end

        % Button pushed function: AvailableAudioFilesPlaylistsButton
        function AvailableAudioFilesPlaylistsButtonPushed(app, event)
            app.AvailableAudioFilesPlaylistsButton.Enable = "off";
            drawnow

            request = matlab.net.http.RequestMessage;
            uri = strcat(strip(app.ServerIPPortEditField.Value,'right','/'), '/list');
            
            response = app_HTTP_request(app, request, uri, app.AvailableAudioFilesPlaylistsButton, [], app.InformationPanel);
            if isempty(response) || isfield(response, "error")
                return
            end

            app.InformationPanel.Value = response.Body.Data;

            app.AvailableAudioFilesPlaylistsButton.Enable = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AudioServerClientGUIUIFigure = uifigure('Visible', 'off');
            app.AudioServerClientGUIUIFigure.Name = 'Audio Server | Client GUI';
            app.TabGroup = uitabgroup(app.AudioServerClientGUIUIFigure);
            app.PlaybackTab = uitab(app.TabGroup);
            app.PlaybackTab.Tooltip = {''};
            app.PlaybackTab.Title = 'Playback';
            app.GeneratePanel = uipanel(app.PlaybackTab);
            app.GeneratePanel.Tooltip = {''};
            app.GeneratePanel.Enable = 'off';
            app.GeneratePanel.Title = '  Generate...';
            app.GridLayoutGenerate = uigridlayout(app.GeneratePanel);
            app.PlaybackGenerateAudioLog = uitextarea(app.GridLayoutGenerate);
            app.PlaybackGenerateAudioLog.Editable = 'off';
            app.PlaybackGenerateAudioLog.Placeholder = 'Latest HTTP response will be shown here...';
            app.PlayButtonGenerateAudio = uibutton(app.GridLayoutGenerate, 'state');
            app.PlayButtonGenerateAudio.ValueChangedFcn = createCallbackFcn(app, @PlayButtonGenerateAudioValueChanged, true);
            app.PlayButtonGenerateAudio.Tooltip = {'Send the request to the server to start playing the audio. You should not send any other request while the audio is playing.'; ''; 'While playing, the audio cannot be stopped until finished, unless the server is terminated.'};
            app.PlayButtonGenerateAudio.Text = '▷  Play';
            app.SweepFnDropDown = uidropdown(app.GridLayoutGenerate);
            app.SweepFnDropDown.Items = {'Linear', 'Quadratic', 'Logarithmic', 'Hyperbolic'};
            app.SweepFnDropDown.Enable = 'off';
            app.SweepFnDropDown.Value = 'Linear';
            app.SweepFunctionLabel = uilabel(app.GridLayoutGenerate);
            app.SweepFunctionLabel.Enable = 'off';
            app.SweepFunctionLabel.Text = 'Sweep Function    ';
            app.EdgesmsHelp = uilabel(app.GridLayoutGenerate);
            app.EdgesmsHelp.Tooltip = {'The rising and falling edges of the tone or sweep. In other words, how long for the audio to fade in and out.'; ''; 'A positive Edges value will add the rising and falling duration to the specified Duration. The actual duration of the audio would then be (Duration + 2 * Edges).'; ''; 'A negative Edges value will make the rising and falling duration inclusive. The specified Duration will remain unchanged. The duration the audio is played at the specified Amplitude would then be (Duration - 2 * Edges).'; ''; 'If Edges is negative, the absolute value of Edges must be less than or equal to (Duration / 2).'; ''; 'The amplitude ramp for rising and falling is linear on the linear amplitude scale (0-1).'; ''; ''; 'Set Edges = 0 to play without fading in and out.'};
            app.EdgesmsHelp.Text = '! ';
            app.DurationmsSpinnerLabel = uilabel(app.GridLayoutGenerate);
            app.DurationmsSpinnerLabel.Text = 'Duration (ms)';
            app.AmplitudedBSpinnerLabel = uilabel(app.GridLayoutGenerate);
            app.AmplitudedBSpinnerLabel.Text = 'Amplitude (dB)';
            app.SampleRateHzSpinnerLabel = uilabel(app.GridLayoutGenerate);
            app.SampleRateHzSpinnerLabel.Text = 'Sample Rate (Hz)';
            app.EdgesmsSpinnerLabel = uilabel(app.GridLayoutGenerate);
            app.EdgesmsSpinnerLabel.Text = 'Edges (ms)';
            app.EdgesmsSpinner = uispinner(app.GridLayoutGenerate);
            app.EdgesmsSpinner.RoundFractionalValues = 'on';
            app.EdgesmsSpinner.ValueDisplayFormat = '%.0f';
            app.EdgesmsSpinner.Tooltip = {'The rising and falling edges of the tone or sweep. In other words, how long for the audio to fade in and out.'; ''; 'A positive Edges value will add the rising and falling duration to the specified Duration. The actual duration of the audio would then be (Duration + 2 * Edges).'; ''; 'A negative Edges value will make the rising and falling duration inclusive. The specified Duration will remain unchanged. The duration the audio is played at the specified Amplitude would then be (Duration - 2 * Edges).'; ''; 'If Edges is negative, the absolute value of Edges must be less than or equal to (Duration / 2).'; ''; 'The amplitude ramp for rising and falling is linear on the linear amplitude scale (0-1).'; ''; ''; 'Set Edges = 0 to play without fading in and out.'};
            app.EdgesmsSpinner.Placeholder = 'Must be an integer, negative or positive.';
            app.SampleRateHzSpinner = uispinner(app.GridLayoutGenerate);
            app.SampleRateHzSpinner.Step = 4000;
            app.SampleRateHzSpinner.Limits = [4000 1000000];
            app.SampleRateHzSpinner.RoundFractionalValues = 'on';
            app.SampleRateHzSpinner.ValueDisplayFormat = '%.0f';
            app.SampleRateHzSpinner.Placeholder = 'Must be a positive integer.';
            app.SampleRateHzSpinner.Value = 192000;
            app.AmplitudedBSpinner = uispinner(app.GridLayoutGenerate);
            app.AmplitudedBSpinner.Limits = [-300 300];
            app.AmplitudedBSpinner.ValueDisplayFormat = '%.3f';
            app.AmplitudedBSpinner.Value = 60;
            app.DurationmsSpinner = uispinner(app.GridLayoutGenerate);
            app.DurationmsSpinner.Step = 100;
            app.DurationmsSpinner.Limits = [0 Inf];
            app.DurationmsSpinner.RoundFractionalValues = 'on';
            app.DurationmsSpinner.ValueDisplayFormat = '%.0f';
            app.DurationmsSpinner.Placeholder = 'Must be a positive integer.';
            app.DurationmsSpinner.Value = 1000;
            app.EndFrequencyHzSpinner = uispinner(app.GridLayoutGenerate);
            app.EndFrequencyHzSpinner.Step = 100;
            app.EndFrequencyHzSpinner.Limits = [0 250000];
            app.EndFrequencyHzSpinner.ValueDisplayFormat = '%.3f';
            app.EndFrequencyHzSpinner.Enable = 'off';
            app.EndFrequencyHzSpinner.Value = 14080;
            app.EndFrequencyHzLabel = uilabel(app.GridLayoutGenerate);
            app.EndFrequencyHzLabel.Enable = 'off';
            app.EndFrequencyHzLabel.Text = 'End Frequency (Hz)';
            app.FrequencyHzSpinner = uispinner(app.GridLayoutGenerate);
            app.FrequencyHzSpinner.Step = 100;
            app.FrequencyHzSpinner.Limits = [0 250000];
            app.FrequencyHzSpinner.ValueDisplayFormat = '%.3f';
            app.FrequencyHzSpinner.Value = 440;
            app.FrequencyHzLabel = uilabel(app.GridLayoutGenerate);
            app.FrequencyHzLabel.Text = 'Frequency (Hz)';
            app.GenerateTypePanel = uipanel(app.GridLayoutGenerate);
            app.GenerateTypePanel.Title = 'Type';
            app.GridLayoutGenerateType = uigridlayout(app.GenerateTypePanel);
            app.GenerateSweepTypeButton = uibutton(app.GridLayoutGenerateType, 'state');
            app.GenerateSweepTypeButton.ValueChangedFcn = createCallbackFcn(app, @GenerateSweepTypeButtonValueChanged, true);
            app.GenerateSweepTypeButton.Text = 'Sweep';
            app.GenerateToneTypeButton = uibutton(app.GridLayoutGenerateType, 'state');
            app.GenerateToneTypeButton.ValueChangedFcn = createCallbackFcn(app, @GenerateToneTypeButtonValueChanged, true);
            app.GenerateToneTypeButton.Text = 'Sine Tone';
            app.GenerateToneTypeButton.Value = true;
            app.GeneratePanelInfo = uilabel(app.GridLayoutGenerate);
            app.GeneratePanelInfo.Tooltip = {'Generate the pure tone or sweep and play it immediately.'; ''; 'The audio will be generated for every call, even if the same tone or sweep is requested multiple times. If performance and speed is a concern, Create the audio file first and play it directly.'};
            app.GeneratePanelInfo.Text = '? ';
            app.SendtoCreateButton = uibutton(app.GridLayoutGenerate, 'push');
            app.SendtoCreateButton.Text = 'Send to Create  ➥';
            app.FromFilesPanel = uipanel(app.PlaybackTab);
            app.FromFilesPanel.Title = '  From Files';
            app.GridLayoutFromFiles = uigridlayout(app.FromFilesPanel);
            app.PlaybackFromFilesLogs = uitextarea(app.GridLayoutFromFiles);
            app.PlaybackFromFilesLogs.Editable = 'off';
            app.PlaybackFromFilesLogs.Placeholder = 'Latest HTTP response will be shown here...';
            app.PlayButtonFromFiles = uibutton(app.GridLayoutFromFiles, 'state');
            app.PlayButtonFromFiles.ValueChangedFcn = createCallbackFcn(app, @PlayButtonFromFilesValueChanged, true);
            app.PlayButtonFromFiles.Tooltip = {'Send the request to the server to start playing the audio. You should not send any other request while the audio is playing.'; ''; 'While playing, the audio cannot be stopped until finished, unless the server is terminated.'};
            app.PlayButtonFromFiles.Text = '▷  Play';
            app.FromFileDropDown = uidropdown(app.GridLayoutFromFiles);
            app.FromFileDropDown.Items = {};
            app.FromFileDropDown.Placeholder = 'Make sure the audio server is on, then hit Refresh to update this list.';
            app.FromFileDropDown.Value = {};
            app.FilterPanel = uipanel(app.GridLayoutFromFiles);
            app.FilterPanel.Title = 'Filter...';
            app.GridLayoutPlaybackFilter = uigridlayout(app.FilterPanel);
            app.FilterPlaylistsButton = uibutton(app.GridLayoutPlaybackFilter, 'state');
            app.FilterPlaylistsButton.ValueChangedFcn = createCallbackFcn(app, @FilterPlaylistsButtonValueChanged, true);
            app.FilterPlaylistsButton.Text = 'Playlists';
            app.FilterAudioFilesButton = uibutton(app.GridLayoutPlaybackFilter, 'state');
            app.FilterAudioFilesButton.ValueChangedFcn = createCallbackFcn(app, @FilterAudioFilesButtonValueChanged, true);
            app.FilterAudioFilesButton.Text = 'Audio Files';
            app.FilterAudioFilesButton.Value = true;
            app.PlaybackRefreshListsButton = uibutton(app.GridLayoutFromFiles, 'push');
            app.PlaybackRefreshListsButton.ButtonPushedFcn = createCallbackFcn(app, @PlaybackRefreshListsButtonPushed, true);
            app.PlaybackRefreshListsButton.Text = '↺  Refresh Lists';
            app.FromFilesPanelInfo = uilabel(app.GridLayoutFromFiles);
            app.FromFilesPanelInfo.Tooltip = {'Play available audio files (.wav, .flac, .mp3,...) or playlists (.txt) on the server.'; ''; 'The available options are shown in the dropdown list. To view the comprehensive list of available audio files and playlists, go to the Information tab.'};
            app.FromFilesPanelInfo.Text = '? ';
            app.PlaybackTypeSwitch = uiswitch(app.PlaybackTab, 'slider');
            app.PlaybackTypeSwitch.Items = {'From Files', 'Generate'};
            app.PlaybackTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @PlaybackTypeSwitchValueChanged, true);
            app.PlaybackTypeSwitch.Value = 'From Files';
            app.PlaybackLamp = uilamp(app.PlaybackTab);
            app.CreateTab = uitab(app.TabGroup);
            app.CreateTab.Title = 'Create';
            app.GridLayout2 = uigridlayout(app.CreateTab);
            app.WindowsBatchFilesPanel = uipanel(app.GridLayout2);
            app.WindowsBatchFilesPanel.Title = 'Windows Batch Files';
            app.GridLayout5 = uigridlayout(app.WindowsBatchFilesPanel);
            app.BatchFilesDropDown = uidropdown(app.GridLayout5);
            app.BatchFilesDropDown.Items = {'Asynchronous Batch Files', 'Synchronous Batch Files'};
            app.BatchFilesDropDown.Tooltip = {'Select which type of .bat files you need.'; ''; '- Synchronous .bat files work using the Windows'' built-in function "curl" in the command line to send the API requests. This type of .bat file waits for the server to respond, and then returns the message sent back by the server. This will block the terminal or other process until the job is finished server-side, say a playlist has finished playing. While this option provides the information about your API call, the "blocking" nature is unsuited for many use cases. If you want to send the API request and immediately do something else, use the Asynchronous option.'; ''; '- Asynchronous .bat files use a custom script "async_get.exe" to send the API requests. Under the hood, "async_get.exe" forwards the API call to a background process, then immediately exit. This way, the request is still made, but the initial .bat script is non-blocking. Asynchronous .bat files are better suited if you trigger them as a part of some bigger scripts or processes. If you need to know the status of your requests or you need to wait until the server-side job is complete before moving forward, use the Synchronous option.'};
            app.BatchFilesDropDown.Value = 'Asynchronous Batch Files';
            app.BatchFilesInfo = uilabel(app.GridLayout5);
            app.BatchFilesInfo.Tooltip = {'Download .bat files that start the audio for each audio files and playlists.'; ''; 'Two different way to make the API calls are available. See the detail tooltip by hovering on the drop-down list.'; ''; 'Select your option, then click Download. You will be prompted to select your download location. The result is a .zip file with all of the batch files and necessary scripts.'};
            app.BatchFilesInfo.Text = ' ? ';
            app.DownloadaszipButton = uibutton(app.GridLayout5, 'state');
            app.DownloadaszipButton.ValueChangedFcn = createCallbackFcn(app, @DownloadaszipButtonValueChanged, true);
            app.DownloadaszipButton.Tooltip = {'You will be prompted to select the download location. Afterwards, extract the .zip file into a folder.'; ''; 'You may need to "Unblock" the .zip file before extracting if Windows throws a complain when you run the .bat file:'; ''; 'Right-click the .zip file > Properties > Unblock (the check box in the lower-right) > Apply'};
            app.DownloadaszipButton.Text = '📥︎ Download as .zip';
            app.ToneSweepPanel = uipanel(app.GridLayout2);
            app.ToneSweepPanel.Title = 'Tone & Sweep';
            app.GridLayoutCreateToneSweep = uigridlayout(app.ToneSweepPanel);
            app.PlayButtonGenerateAudio_2 = uibutton(app.GridLayoutCreateToneSweep, 'state');
            app.PlayButtonGenerateAudio_2.Tooltip = {'Send the request to the server to start playing the audio. You should not send any other request while the audio is playing.'; ''; 'While playing, the audio cannot be stopped until finished, unless the server is terminated.'};
            app.PlayButtonGenerateAudio_2.Text = '📥︎  Create & Download';
            app.SweepFnDropDown_2 = uidropdown(app.GridLayoutCreateToneSweep);
            app.SweepFnDropDown_2.Items = {'Linear', 'Quadratic', 'Logarithmic', 'Hyperbolic'};
            app.SweepFnDropDown_2.Enable = 'off';
            app.SweepFnDropDown_2.Value = 'Linear';
            app.SweepFunctionLabel_2 = uilabel(app.GridLayoutCreateToneSweep);
            app.SweepFunctionLabel_2.Enable = 'off';
            app.SweepFunctionLabel_2.Text = 'Sweep Function    ';
            app.EdgesmsHelp_2 = uilabel(app.GridLayoutCreateToneSweep);
            app.EdgesmsHelp_2.Tooltip = {'The rising and falling edges of the tone or sweep. In other words, how long for the audio to fade in and out.'; ''; 'A positive Edges value will add the rising and falling duration to the specified Duration. The actual duration of the audio would then be (Duration + 2 * Edges).'; ''; 'A negative Edges value will make the rising and falling duration inclusive. The specified Duration will remain unchanged. The duration the audio is played at the specified Amplitude would then be (Duration - 2 * Edges).'; ''; 'If Edges is negative, the absolute value of Edges must be less than or equal to (Duration / 2).'; ''; 'The amplitude ramp for rising and falling is linear on the linear amplitude scale (0-1).'; ''; ''; 'Set Edges = 0 to play without fading in and out.'};
            app.EdgesmsHelp_2.Text = '! ';
            app.DurationmsSpinner_2Label = uilabel(app.GridLayoutCreateToneSweep);
            app.DurationmsSpinner_2Label.Text = 'Duration (ms)';
            app.DurationmsSpinner_2 = uispinner(app.GridLayoutCreateToneSweep);
            app.DurationmsSpinner_2.Step = 100;
            app.DurationmsSpinner_2.Limits = [0 Inf];
            app.DurationmsSpinner_2.RoundFractionalValues = 'on';
            app.DurationmsSpinner_2.ValueDisplayFormat = '%.0f';
            app.DurationmsSpinner_2.Placeholder = 'Must be a positive integer.';
            app.DurationmsSpinner_2.Value = 1000;
            app.AmplitudedBSpinner_2Label = uilabel(app.GridLayoutCreateToneSweep);
            app.AmplitudedBSpinner_2Label.Text = 'Amplitude (dB)';
            app.AmplitudedBSpinner_2 = uispinner(app.GridLayoutCreateToneSweep);
            app.AmplitudedBSpinner_2.Limits = [-300 300];
            app.AmplitudedBSpinner_2.ValueDisplayFormat = '%.3f';
            app.AmplitudedBSpinner_2.Value = 60;
            app.SampleRateHzSpinner_2Label = uilabel(app.GridLayoutCreateToneSweep);
            app.SampleRateHzSpinner_2Label.Text = 'Sample Rate (Hz)';
            app.SampleRateHzSpinner_2 = uispinner(app.GridLayoutCreateToneSweep);
            app.SampleRateHzSpinner_2.Step = 4000;
            app.SampleRateHzSpinner_2.Limits = [4000 1000000];
            app.SampleRateHzSpinner_2.RoundFractionalValues = 'on';
            app.SampleRateHzSpinner_2.ValueDisplayFormat = '%.0f';
            app.SampleRateHzSpinner_2.Placeholder = 'Must be a positive integer.';
            app.SampleRateHzSpinner_2.Value = 192000;
            app.EdgesmsSpinner_2Label = uilabel(app.GridLayoutCreateToneSweep);
            app.EdgesmsSpinner_2Label.Text = 'Edges (ms)';
            app.EdgesmsSpinner_2 = uispinner(app.GridLayoutCreateToneSweep);
            app.EdgesmsSpinner_2.RoundFractionalValues = 'on';
            app.EdgesmsSpinner_2.ValueDisplayFormat = '%.0f';
            app.EdgesmsSpinner_2.Tooltip = {'The rising and falling edges of the tone or sweep. In other words, how long for the audio to fade in and out.'; ''; 'A positive Edges value will add the rising and falling duration to the specified Duration. The actual duration of the audio would then be (Duration + 2 * Edges).'; ''; 'A negative Edges value will make the rising and falling duration inclusive. The specified Duration will remain unchanged. The duration the audio is played at the specified Amplitude would then be (Duration - 2 * Edges).'; ''; 'If Edges is negative, the absolute value of Edges must be less than or equal to (Duration / 2).'; ''; 'The amplitude ramp for rising and falling is linear on the linear amplitude scale (0-1).'; ''; ''; 'Set Edges = 0 to play without fading in and out.'};
            app.EdgesmsSpinner_2.Placeholder = 'Must be an integer, negative or positive.';
            app.EndFrequencyHzSpinner_2 = uispinner(app.GridLayoutCreateToneSweep);
            app.EndFrequencyHzSpinner_2.Step = 100;
            app.EndFrequencyHzSpinner_2.Limits = [0 250000];
            app.EndFrequencyHzSpinner_2.ValueDisplayFormat = '%.3f';
            app.EndFrequencyHzSpinner_2.Enable = 'off';
            app.EndFrequencyHzSpinner_2.Value = 14080;
            app.EndFrequencyHzLabel_2 = uilabel(app.GridLayoutCreateToneSweep);
            app.EndFrequencyHzLabel_2.Enable = 'off';
            app.EndFrequencyHzLabel_2.Text = 'End Frequency (Hz)';
            app.FrequencyHzSpinner_2 = uispinner(app.GridLayoutCreateToneSweep);
            app.FrequencyHzSpinner_2.Step = 100;
            app.FrequencyHzSpinner_2.Limits = [0 250000];
            app.FrequencyHzSpinner_2.ValueDisplayFormat = '%.3f';
            app.FrequencyHzSpinner_2.Value = 440;
            app.FrequencyHzLabel_2 = uilabel(app.GridLayoutCreateToneSweep);
            app.FrequencyHzLabel_2.Text = 'Frequency (Hz)';
            app.GenerateTypePanel_2 = uipanel(app.GridLayoutCreateToneSweep);
            app.GenerateTypePanel_2.Title = 'Type';
            app.GridLayoutGenerateType_2 = uigridlayout(app.GenerateTypePanel_2);
            app.GenerateSweepTypeButton_2 = uibutton(app.GridLayoutGenerateType_2, 'state');
            app.GenerateSweepTypeButton_2.Text = 'Sweep';
            app.GenerateToneTypeButton_2 = uibutton(app.GridLayoutGenerateType_2, 'state');
            app.GenerateToneTypeButton_2.Text = 'Sine Tone';
            app.GenerateToneTypeButton_2.Value = true;
            app.CreateToneSweepInfo = uilabel(app.GridLayoutCreateToneSweep);
            app.CreateToneSweepInfo.Tooltip = {'Create the pure tone or sweep as a .wav file to be downloaded.'; ''; 'You will be prompted to select the download location after clicking the Create & Download button.'};
            app.CreateToneSweepInfo.Text = '? ';
            app.CreateRandomPlaylist = uipanel(app.GridLayout2);
            app.CreateRandomPlaylist.Title = 'Random Playlist';
            app.GridLayout3 = uigridlayout(app.CreateRandomPlaylist);
            app.CreateLamp = uilamp(app.GridLayout2);
            app.InformationTab = uitab(app.TabGroup);
            app.InformationTab.Title = 'Information';
            app.GridLayout = uigridlayout(app.InformationTab);
            app.InformationPanel = uitextarea(app.GridLayout);
            app.InformationPanel.Editable = 'off';
            app.InformationPanel.Placeholder = 'Requested information will be shown here!';
            app.MainAPIDocumentationButton = uibutton(app.GridLayout, 'push');
            app.MainAPIDocumentationButton.ButtonPushedFcn = createCallbackFcn(app, @MainAPIDocumentationButtonPushed, true);
            app.MainAPIDocumentationButton.Text = 'Main API Documentation';
            app.AvailableAudioFilesPlaylistsButton = uibutton(app.GridLayout, 'push');
            app.AvailableAudioFilesPlaylistsButton.ButtonPushedFcn = createCallbackFcn(app, @AvailableAudioFilesPlaylistsButtonPushed, true);
            app.AvailableAudioFilesPlaylistsButton.Text = 'Available Audio Files & Playlists';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.GridLayoutSettings = uigridlayout(app.SettingsTab);
            app.ServerIPPortLabel = uilabel(app.GridLayoutSettings);
            app.ServerIPPortLabel.Text = 'Server IP : Port  ';
            app.ShutdownButton = uibutton(app.GridLayoutSettings, 'push');
            app.ShutdownButton.ButtonPushedFcn = createCallbackFcn(app, @ShutdownButtonPushed, true);
            app.ShutdownButton.Tooltip = {'Shutdown the audio server. Literally, shut it down. Gone.'; ''; 'If you do not have access to the server computer, you will NOT be able to start the audio server app back up.'; ''; 'Unless you want to actually shutdown (close) the audio server app, consider using the Restart option instead.'};
            app.ShutdownButton.Text = 'Shutdown';
            app.ShutdownServerLabel = uilabel(app.GridLayoutSettings);
            app.ShutdownServerLabel.Tooltip = {'Shutdown the audio server. Literally, shut it down. Gone.'; ''; 'If you do not have access to the server computer, you will NOT be able to start the audio server app back up.'; ''; 'Unless you want to actually shutdown (close) the audio server app, consider using the Restart option instead.'};
            app.ShutdownServerLabel.Text = 'Shutdown Server  ';
            app.ManualRestartURI = uitextarea(app.GridLayoutSettings);
            app.ManualRestartURI.Editable = 'off';
            app.ManualRestartURI.Tooltip = {'Paste this URI into a browser URL bar, or run from the terminal with "curl" or "wget".'; ''; 'Example:'; ''; 'curl http://server_ip:port/restart?restart=true'; ''; 'wget http://server_ip:port/restart?restart=true'};
            app.OrusetheURIbelowtoRestarttheservermanuallyLabel = uilabel(app.GridLayoutSettings);
            app.OrusetheURIbelowtoRestarttheservermanuallyLabel.Text = 'Or use the URI below to Restart the server manually:';
            app.RestartButton = uibutton(app.GridLayoutSettings, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Tooltip = {'Restart everything. From scratch.'; ''; 'Attempt to shutdown, then restart the audio server. This will stop all playing audio or process. When the server is restarted, a new log file will be use instead.'; ''; 'New playlist files created by the old process will be kept and not affected.'};
            app.RestartButton.Text = 'Restart';
            app.RestartServerLabel = uilabel(app.GridLayoutSettings);
            app.RestartServerLabel.Tooltip = {'Restart everything. From scratch.'; ''; 'Attempt to shutdown, then restart the audio server. This will stop all playing audio or process. When the server is restarted, a new log file will be use instead.'; ''; 'New playlist files created by the old process will be kept and not affected.'};
            app.RestartServerLabel.Text = 'Restart Server  ';
            app.ServerIPPortEditField = uieditfield(app.GridLayoutSettings, 'text');
            app.ServerIPPortEditField.ValueChangedFcn = createCallbackFcn(app, @ServerIPPortEditFieldValueChanged, true);
            app.ServerIPPortEditField.Placeholder = 'http://127.0.0.1:5055';
            app.ServerIPPortEditField.Value = 'http://127.0.0.1:5055';
            % Show the figure after all components are created
            app.AudioServerClientGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClientGUI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AudioServerClientGUIUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.AudioServerClientGUIUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AudioServerClientGUIUIFigure)
        end
    end
end